classdef LTE_scan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LTEscan                         matlab.ui.Figure
        InputparametersPanel            matlab.ui.container.Panel
        Search_StepinMHzEditFieldLabel  matlab.ui.control.Label
        Search_StepinMHzEditField       matlab.ui.control.NumericEditField
        stopButton                      matlab.ui.control.Button
        Start_FrequencyinMHzEditFieldLabel  matlab.ui.control.Label
        Start_FrequencyinMHzEditField   matlab.ui.control.NumericEditField
        Stop_FrequencyinMHzEditFieldLabel  matlab.ui.control.Label
        Stop_FrequencyinMHzEditField    matlab.ui.control.NumericEditField
        scanButton                      matlab.ui.control.Button
        ScanResultsTextAreaLabel        matlab.ui.control.Label
        ScanResultsTextArea             matlab.ui.control.TextArea
        scanningFrequencyGaugeLabel     matlab.ui.control.Label
        scanningFrequencyGauge          matlab.ui.control.LinearGauge
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = public)
        proccessFlag = 1; % Description
    end
    

    methods (Access = private)

        % Button pushed function: scanButton
        function scanButtonPushed(app, event)
            app.proccessFlag = 1;
            app.Label.Text = 'Creating Radio Link ...';
            radio = rtlRadio();
            app.Label.Text = 'Radio Link established ...';
            Fstart = app.Start_FrequencyinMHzEditField.Value;
            Fstop  = app.Stop_FrequencyinMHzEditField.Value;
            step   = app.Search_StepinMHzEditField.Value;
            FreqArray = Fstart:step:Fstop;
            app.scanningFrequencyGauge.Limits = [Fstart,Fstop];
            app.scanningFrequencyGauge.Visible = 'on';
            app.scanningFrequencyGaugeLabel.Visible = 'on';
            app.ScanResultsTextArea.Visible = 'on';
            app.ScanResultsTextAreaLabel.Visible = 'on';
            app.Label.Text = 'Scanning ...';
            while app.proccessFlag == 1
                for F = FreqArray
                    if app.proccessFlag == 1
                        app.scanningFrequencyGauge.Value = F;
                        radio.CenterFrequency  = F * 1e6;
                        data = zeros(4*1.92e4,1);
                        for i = 1:4
                            data(19200*(i-1)+1:19200*i) = radio();
                        end
                        [detectFlag,timeOffset,freqOffset,PSSn] = pssdetect_FDD(data,128);
                        if detectFlag
                            cellID = SSSdetect(data,timeOffset,freqOffset,PSSn);
                            d = app.ScanResultsTextArea.Value;
                            app.ScanResultsTextArea.Value = ...
                                [d;{sprintf('Frequency = %6.2f CellId = %d', F,cellID)}];
                        end
                        pause(0.1);
                    end
                end
            end
            app.Label.Text = 'Scan Finished successfully';
            release(radio)
            
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            app.proccessFlag = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LTEscan = uifigure;
            app.LTEscan.Name = 'LTEscan';
            app.InputparametersPanel = uipanel(app.LTEscan);
            app.InputparametersPanel.Title = 'Input parameters';
            app.Search_StepinMHzEditFieldLabel = uilabel(app.InputparametersPanel);
            app.Search_StepinMHzEditFieldLabel.Text = 'Search_Step in MHz';
            app.Search_StepinMHzEditField = uieditfield(app.InputparametersPanel, 'numeric');
            app.stopButton = uibutton(app.InputparametersPanel, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.Start_FrequencyinMHzEditFieldLabel = uilabel(app.InputparametersPanel);
            app.Start_FrequencyinMHzEditFieldLabel.Text = 'Start_Frequency in MHz';
            app.Start_FrequencyinMHzEditField = uieditfield(app.InputparametersPanel, 'numeric');
            app.Stop_FrequencyinMHzEditFieldLabel = uilabel(app.InputparametersPanel);
            app.Stop_FrequencyinMHzEditFieldLabel.Text = 'Stop_Frequency in MHz';
            app.Stop_FrequencyinMHzEditField = uieditfield(app.InputparametersPanel, 'numeric');
            app.scanButton = uibutton(app.InputparametersPanel, 'push');
            app.scanButton.ButtonPushedFcn = createCallbackFcn(app, @scanButtonPushed, true);
            app.scanButton.Text = 'scan';
            app.ScanResultsTextAreaLabel = uilabel(app.LTEscan);
            app.ScanResultsTextAreaLabel.Visible = 'off';
            app.ScanResultsTextAreaLabel.Text = 'Scan Results';
            app.ScanResultsTextArea = uitextarea(app.LTEscan);
            app.ScanResultsTextArea.Visible = 'off';
            app.scanningFrequencyGaugeLabel = uilabel(app.LTEscan);
            app.scanningFrequencyGaugeLabel.Visible = 'off';
            app.scanningFrequencyGaugeLabel.Text = 'scanning Frequency';
            app.scanningFrequencyGauge = uigauge(app.LTEscan, 'linear');
            app.scanningFrequencyGauge.Visible = 'off';
            app.Label = uilabel(app.LTEscan);
            app.Label.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LTE_scan

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LTEscan)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LTEscan)
        end
    end
end